classdef interfacedesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NeuralinterfaceButtonGroup  matlab.ui.container.ButtonGroup
        Button31                    matlab.ui.control.Button
        Button28                    matlab.ui.control.Button
        Button30                    matlab.ui.control.Button
        Button29                    matlab.ui.control.Button
        Button27                    matlab.ui.control.Button
        Button24                    matlab.ui.control.Button
        Button25                    matlab.ui.control.Button
        Button26                    matlab.ui.control.Button
        Button21                    matlab.ui.control.Button
        Button22                    matlab.ui.control.Button
        Button20                    matlab.ui.control.Button
        Button19                    matlab.ui.control.Button
        Button23                    matlab.ui.control.Button
        Button18                    matlab.ui.control.Button
        Button17                    matlab.ui.control.Button
        Button16                    matlab.ui.control.Button
        Button15                    matlab.ui.control.Button
        Button14                    matlab.ui.control.Button
        Button13                    matlab.ui.control.Button
        Button12                    matlab.ui.control.Button
        Button11                    matlab.ui.control.Button
        Button10                    matlab.ui.control.Button
        Button9                     matlab.ui.control.Button
        Button8                     matlab.ui.control.Button
        Button7                     matlab.ui.control.Button
        Button6                     matlab.ui.control.Button
        Button5                     matlab.ui.control.Button
        Button4                     matlab.ui.control.Button
        Button3                     matlab.ui.control.Button
        ModesButtonGroup            matlab.ui.container.ButtonGroup
        NotchBandpassButton_2       matlab.ui.control.RadioButton
        BandpassButton_2            matlab.ui.control.RadioButton
        NotchButton_2               matlab.ui.control.RadioButton
        Button25_32                 matlab.ui.control.Button
        Button17_24                 matlab.ui.control.Button
        Button9_16                  matlab.ui.control.Button
        Button1_8                   matlab.ui.control.Button
        ViewallButton               matlab.ui.control.Button
        Button2                     matlab.ui.control.Button
        Button1                     matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        Button                      matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        Property % Description
           
            t = evalin('base', 't');
            amplifier_data = evalin('base', 'amplifier_data');
            f_notch = evalin('base', 'f_notch');
            fs = evalin ('base','fs' );
            f3 = evalin ('base', 'f3');
            f4 = evalin ('base', 'f4');
            filter_1_to_300_Hz = evalin ('base', 'filter_1_to_300_Hz');
            filter_lowfreq_notch =  evalin ('base', 'filter_lowfreq_notch');
    end
    
    methods (Access = private)
        
        function single_channel_button(app, channel)
             if app.NotchButton_2.Value 
                notch (app.t, app.amplifier_data, app.f_notch, app.fs, channel);
           
           elseif app.BandpassButton_2.Value 
              filter_highfreq(app.f3, app.f4, app.fs, app.t, app.filter_1_to_300_Hz, channel);
               
             elseif app.NotchBandpassButton_2.Value 
               filter_highfreq(app.f3, app.f4, app.fs, app.t, app.filter_lowfreq_notch, channel);
               
           end
            
        end
        
        function multiple_channel_button(app, start_channel, all_channels)
            
            if app.NotchButton_2.Value 
               notch_all(app.t, app.amplifier_data, app.f_notch, app.fs, start_channel, all_channels);
            
            elseif app.BandpassButton_2.Value 
                filter_highfreq_all(app.f3, app.f4, app.fs, app.t, app.filter_1_to_300_Hz , start_channel, all_channels);
            
            elseif app.NotchBandpassButton_2.Value 
                filter_highfreq_all(app.f3, app.f4, app.fs, app.t, app.filter_lowfreq_notch, start_channel, all_channels);
            end
            
        end
        
        function results = func3(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button1
        function Button1Pushed(app, event)
           
            channel = 1;  
         app.single_channel_button(channel)
      
        end

        % Button pushed function: Button1_8
        function Button1_8Pushed(app, event)
        
            start_channel = 1;
            all_channels = 0;
            
            app.multiple_channel_button(start_channel, all_channels)
            
        end

        % Button pushed function: Button9_16
        function Button9_16Pushed(app, event)
            
            start_channel = 9;
            all_channels = 0;
            
            app.multiple_channel_button(start_channel, all_channels)
        end

        % Button pushed function: Button17_24
        function Button17_24Pushed(app, event)
            
           start_channel = 17;
           all_channels = 0;
           
           app.multiple_channel_button(start_channel, all_channels)
        end

        % Button pushed function: Button25_32
        function Button25_32Pushed(app, event)
            
            start_channel = 25;
            all_channels = 0;
            
            app.multiple_channel_button(start_channel, all_channels)
        end

        % Button pushed function: ViewallButton
        function ViewallButtonPushed(app, event)
            
             start_channel = 1;
             all_channels = 1;
             
             app.multiple_channel_button(start_channel, all_channels)
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
           
            channel = 2;
            app.single_channel_button(channel)
     
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
             
            channel = 3;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
             
            channel = 4;  
             app.single_channel_button(channel)

        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
             
            channel = 5;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button6
        function Button6Pushed(app, event)
            
            channel = 6;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button7
        function Button7Pushed(app, event)
            
            channel = 7;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button8
        function Button8Pushed(app, event)
                  
            channel = 8;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button9
        function Button9Pushed(app, event)
                  
            channel = 9;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button10
        function Button10Pushed(app, event)
                  
            channel = 10;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button11
        function Button11Pushed(app, event)
                  
            channel = 11;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button12
        function Button12Pushed(app, event)
                  
            channel = 12;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button13
        function Button13Pushed(app, event)
                  
            channel = 13;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button14
        function Button14Pushed(app, event)
                  
            channel = 14;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button15
        function Button15Pushed(app, event)
                  
            channel = 15;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button16
        function Button16Pushed(app, event)
                  
            channel = 16;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button17
        function Button17Pushed(app, event)
                  
            channel = 17;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button18
        function Button18Pushed(app, event)
                  
            channel = 18;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button19
        function Button19Pushed(app, event)
                  
            channel = 19;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button20
        function Button20Pushed(app, event)
                  
            channel = 20;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button21
        function Button21Pushed(app, event)
                  
            channel = 21;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button22
        function Button22Pushed(app, event)
                  
            channel = 22;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button23
        function Button23Pushed(app, event)
                  
            channel = 23;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button24
        function Button24Pushed(app, event)
                  
            channel = 24;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button25
        function Button25Pushed(app, event)
                  
            channel = 25;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button26
        function Button26Pushed(app, event)
                  
            channel = 26;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button27
        function Button27Pushed(app, event)
                  
            channel = 27;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button28
        function Button28Pushed(app, event)
                  
            channel = 28;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button29
        function Button29Pushed(app, event)
                  
            channel = 29;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button30
        function Button30Pushed(app, event)
                  
            channel = 30;  
             app.single_channel_button(channel)
        end

        % Button pushed function: Button31
        function Button31Pushed(app, event)
                  
            channel = 31;  
             app.single_channel_button(channel)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NeuralinterfaceButtonGroup = uibuttongroup(app.UIFigure);
            app.NeuralinterfaceButtonGroup.Title = 'Neural interface';
            app.Button = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button.Text = '0';
            app.Button.Value = true;
            app.Image = uiimage(app.NeuralinterfaceButtonGroup);
            app.Image.ImageSource = 'Neural interface zoom.png';
            app.Button1 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '1';
            app.Button2 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '2';
            app.ViewallButton = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.ViewallButton.ButtonPushedFcn = createCallbackFcn(app, @ViewallButtonPushed, true);
            app.ViewallButton.Text = 'View all';
            app.Button1_8 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button1_8.ButtonPushedFcn = createCallbackFcn(app, @Button1_8Pushed, true);
            app.Button1_8.Text = '1 - 8';
            app.Button9_16 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button9_16.ButtonPushedFcn = createCallbackFcn(app, @Button9_16Pushed, true);
            app.Button9_16.Text = '9 - 16';
            app.Button17_24 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button17_24.ButtonPushedFcn = createCallbackFcn(app, @Button17_24Pushed, true);
            app.Button17_24.Text = '17 - 24';
            app.Button25_32 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button25_32.ButtonPushedFcn = createCallbackFcn(app, @Button25_32Pushed, true);
            app.Button25_32.Text = '25 -32';
            app.ModesButtonGroup = uibuttongroup(app.NeuralinterfaceButtonGroup);
            app.ModesButtonGroup.Title = 'Modes';
            app.NotchButton_2 = uiradiobutton(app.ModesButtonGroup);
            app.NotchButton_2.Text = 'Notch';
            app.NotchButton_2.Value = true;
            app.BandpassButton_2 = uiradiobutton(app.ModesButtonGroup);
            app.BandpassButton_2.Text = 'Bandpass';
            app.NotchBandpassButton_2 = uiradiobutton(app.ModesButtonGroup);
            app.NotchBandpassButton_2.Text = 'Notch + Bandpass';
            app.Button3 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = '3';
            app.Button4 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = '4';
            app.Button5 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = '5';
            app.Button6 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button6.Text = '6';
            app.Button7 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button7.Text = '7';
            app.Button8 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button8.Text = '8';
            app.Button9 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button9.Text = '9';
            app.Button10 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button10.ButtonPushedFcn = createCallbackFcn(app, @Button10Pushed, true);
            app.Button10.Text = '10';
            app.Button11 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button11.ButtonPushedFcn = createCallbackFcn(app, @Button11Pushed, true);
            app.Button11.Text = '11';
            app.Button12 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button12.ButtonPushedFcn = createCallbackFcn(app, @Button12Pushed, true);
            app.Button12.Text = '12';
            app.Button13 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button13.ButtonPushedFcn = createCallbackFcn(app, @Button13Pushed, true);
            app.Button13.Text = '13';
            app.Button14 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button14.ButtonPushedFcn = createCallbackFcn(app, @Button14Pushed, true);
            app.Button14.Text = '14';
            app.Button15 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button15.ButtonPushedFcn = createCallbackFcn(app, @Button15Pushed, true);
            app.Button15.Text = '15';
            app.Button16 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button16.ButtonPushedFcn = createCallbackFcn(app, @Button16Pushed, true);
            app.Button16.Text = '16';
            app.Button17 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button17.ButtonPushedFcn = createCallbackFcn(app, @Button17Pushed, true);
            app.Button17.Text = '17';
            app.Button18 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button18.ButtonPushedFcn = createCallbackFcn(app, @Button18Pushed, true);
            app.Button18.Text = '18';
            app.Button23 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button23.ButtonPushedFcn = createCallbackFcn(app, @Button23Pushed, true);
            app.Button23.Text = '23';
            app.Button19 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button19.ButtonPushedFcn = createCallbackFcn(app, @Button19Pushed, true);
            app.Button19.Text = '19';
            app.Button20 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button20.ButtonPushedFcn = createCallbackFcn(app, @Button20Pushed, true);
            app.Button20.Text = '20';
            app.Button22 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button22.ButtonPushedFcn = createCallbackFcn(app, @Button22Pushed, true);
            app.Button22.Text = '22';
            app.Button21 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button21.ButtonPushedFcn = createCallbackFcn(app, @Button21Pushed, true);
            app.Button21.Text = '21';
            app.Button26 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button26.ButtonPushedFcn = createCallbackFcn(app, @Button26Pushed, true);
            app.Button26.Text = '26';
            app.Button25 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button25.ButtonPushedFcn = createCallbackFcn(app, @Button25Pushed, true);
            app.Button25.Text = '25';
            app.Button24 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button24.ButtonPushedFcn = createCallbackFcn(app, @Button24Pushed, true);
            app.Button24.Text = '24';
            app.Button27 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button27.ButtonPushedFcn = createCallbackFcn(app, @Button27Pushed, true);
            app.Button27.Text = '27';
            app.Button29 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button29.ButtonPushedFcn = createCallbackFcn(app, @Button29Pushed, true);
            app.Button29.Text = '29';
            app.Button30 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button30.ButtonPushedFcn = createCallbackFcn(app, @Button30Pushed, true);
            app.Button30.Text = '30';
            app.Button28 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button28.ButtonPushedFcn = createCallbackFcn(app, @Button28Pushed, true);
            app.Button28.Text = '28';
            app.Button31 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button31.ButtonPushedFcn = createCallbackFcn(app, @Button31Pushed, true);
            app.Button31.Text = '31';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfacedesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end